classdef Hw17011076 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShowConnectionLineCheckBox      matlab.ui.control.CheckBox
        FrequencyLabel                  matlab.ui.control.Label
        CoefficientsLabel               matlab.ui.control.Label
        Coefficient5ImaginerEditField   matlab.ui.control.NumericEditField
        Coefficient5ImaginerEditFieldLabel  matlab.ui.control.Label
        Coefficient5RealEditField       matlab.ui.control.NumericEditField
        Coefficient5RealEditFieldLabel  matlab.ui.control.Label
        Coefficient4ImaginerEditField   matlab.ui.control.NumericEditField
        Coefficient4ImaginerEditFieldLabel  matlab.ui.control.Label
        Coefficient4RealEditField       matlab.ui.control.NumericEditField
        Coefficient4RealEditFieldLabel  matlab.ui.control.Label
        Coefficient3ImaginerEditField   matlab.ui.control.NumericEditField
        Coefficient3ImaginerEditFieldLabel  matlab.ui.control.Label
        Coefficient3RealEditField       matlab.ui.control.NumericEditField
        Coefficient3RealEditFieldLabel  matlab.ui.control.Label
        Coefficient2ImaginerEditField   matlab.ui.control.NumericEditField
        Coefficient2ImaginerEditFieldLabel  matlab.ui.control.Label
        Coefficient2RealEditField       matlab.ui.control.NumericEditField
        Coefficient2RealEditFieldLabel  matlab.ui.control.Label
        Coefficient1ImaginerEditField   matlab.ui.control.NumericEditField
        Coefficient1ImaginerEditFieldLabel  matlab.ui.control.Label
        Coefficient1RealEditField       matlab.ui.control.NumericEditField
        Coefficient1RealEditFieldLabel  matlab.ui.control.Label
        FundamentalFrequencyEditFieldLabel  matlab.ui.control.Label
        FundamentalFrequencyEditField   matlab.ui.control.NumericEditField
        StartSimulationButton           matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
            f = 0;
            tt = 0: 2*pi/10000 :(6*pi);
            theta  = 0: 2*pi/100 :2*pi;
            
            phasor1;
            phasor2;
            phasor3;
            phasor4;
            phasor5;

            xx1;
            xx2;
            xx3;
            xx4;
            xx5;

            yy1;
            yy2;
            yy3;
            yy4;
            yy5;

            x1 = 0;
            x2 = 0;
            x3 = 0;
            x4 = 0;
            x5 = 0;

            y1 = 0;
            y2 = 0;
            y3 = 0;
            y4 = 0;
            y5 = 0;

            isReset = 0;
    end
    
    methods (Access = public)

        function swf = sinusWaveForm(app, coeff, mul)
            swf = (mul*2*pi*app.tt*app.f+angle(coeff));
        end

        function circle = getCircle(app, type, amp, mul)
            if(type)
                circle = amp/(mul*pi)*sin(mul*app.theta);
            else 
                circle = amp/(mul*pi)*cos(mul*app.theta);
            end
        end

        function reset(app)
            app.isReset = 0;
            app.StartSimulationButton.Text = 'Start Simulation';
            cla(app.UIAxes);
            cla(app.UIAxes2);

            app.x1 = app.Coefficient1RealEditField.Value;
            app.x2 = app.Coefficient2RealEditField.Value;
            app.x3 = app.Coefficient3RealEditField.Value;
            app.x4 = app.Coefficient4RealEditField.Value;
            app.x5 = app.Coefficient5RealEditField.Value;
            
            app.y1 = app.Coefficient1ImaginerEditField.Value;
            app.y2 = app.Coefficient2ImaginerEditField.Value;
            app.y3 = app.Coefficient3ImaginerEditField.Value;
            app.y4 = app.Coefficient4ImaginerEditField.Value;
            app.y5 = app.Coefficient5ImaginerEditField.Value;
        end

            function update(app)

                coefficient1 = app.x1+1j*app.y1;
                coefficient2 = app.x2+1j*app.y2;
                coefficient3 = app.x3+1j*app.y3;
                coefficient4 = app.x4+1j*app.y4;
                coefficient5 = app.x5+1j*app.y5;

                % First Harmonic
                amp1 = abs(coefficient1);
                app.phasor1=amp1*exp(1j*sinusWaveForm(app, coefficient1, 1))/pi;

                app.xx1=getCircle(app, 0, amp1, 1);
                app.yy1=getCircle(app, 1, amp1, 1);
            
                % Second Harmonic
                amp2 = abs(coefficient2);
                app.phasor2=amp2*exp(1j*sinusWaveForm(app, coefficient2, 3))/(3*pi);
    
                app.xx2=getCircle(app, 0, amp2, 3);
                app.yy2=getCircle(app, 1, amp2, 3);
                
                % Third Harmonic
                amp3 = abs(coefficient3);
                app.phasor3=amp3*exp(1j*sinusWaveForm(app, coefficient3, 5))/(5*pi);
    
                app.xx3=getCircle(app, 0, amp3, 5);
                app.yy3=getCircle(app, 1, amp3, 5);
                
                % Fourth Harmonic
                amp4 = abs(coefficient4);
                app.phasor4=amp4*exp(1j*sinusWaveForm(app, coefficient4, 7))/(7*pi);
    
                app.xx4=getCircle(app, 0, amp4, 7);
                app.yy4=getCircle(app, 1, amp4, 7);
                
                % Fifth Harmonic
                amp5 = abs(coefficient5);
                app.phasor5=amp5*exp(1j*sinusWaveForm(app, coefficient5, 9))/(9*pi);
    
                app.xx5=getCircle(app, 0, amp5, 9);
                app.yy5=getCircle(app, 1, amp5, 9);
                
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            
            if(app.isReset)
                reset(app);
            else
                update(app);
                app.StartSimulationButton.Text = 'Reset Simulation';
                app.isReset = 1;
                for i= 1:length(app.tt)

                    if(app.isReset==0)
                        break;
                    end

                    hold(app.UIAxes,'off');
                    hold(app.UIAxes2,'off');

                    app.x1=real(app.phasor1(i)); app.y1=imag(app.phasor1(i));
                    app.x2=real(app.phasor2(i)); app.y2=imag(app.phasor2(i));
                    app.x3=real(app.phasor3(i)); app.y3=imag(app.phasor3(i));
                    app.x4=real(app.phasor4(i)); app.y4=imag(app.phasor4(i));
                    app.x5=real(app.phasor5(i)); app.y5=imag(app.phasor5(i));
                
                    %  -- 1st harmonic ---
                    plot(app.UIAxes,[0 app.x1], [0 app.y1],'r');hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,app.xx1,app.yy1,"r"); 
                
                    %  -- 2nd harmonic ---
                    plot(app.UIAxes,[app.x1 app.x1+app.x2], [app.y1 app.y1+app.y2],'b'); hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,app.xx2+app.x1,app.yy2+app.y1,"b");  
                
                    %  -- 3rd harmonic ---
                    plot(app.UIAxes,[app.x1+app.x2 app.x1+app.x2+app.x3], [app.y1+app.y2 app.y1+app.y2+app.y3],'k'); hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,app.xx3+app.x2+app.x1,app.yy3+app.y2+app.y1,"k");  
                
                    %  -- 4th harmonic ---
                    plot(app.UIAxes,[app.x1+app.x2+app.x3 app.x1+app.x2+app.x3+app.x4], [app.y1+app.y2+app.y3 app.y1+app.y2+app.y3+app.y4],'g'); hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,app.xx4+app.x3+app.x2+app.x1,app.yy4+app.y3+app.y2+app.y1,"g"); 
                
                    %  -- 5th harmonic ---
                    plot(app.UIAxes,[app.x1+app.x2+app.x3+app.x4 app.x1+app.x2+app.x3+app.x4+app.x5], [app.y1+app.y2+app.y3+app.y4 app.y1+app.y2+app.y3+app.y4+app.y5],'m'); hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,app.xx5+app.x4+app.x3+app.x2+app.x1,app.yy5+app.y4+app.y3+app.y2+app.y1,"m"); 

                    plot(app.UIAxes2,app.tt(1:i),imag(app.phasor1(1:i))+imag(app.phasor2(1:i))+imag(app.phasor3(1:i))+imag(app.phasor4(1:i))+imag(app.phasor5(1:i)),'m','LineWidth',2); hold(app.UIAxes2,'on');
                
                    if(app.ShowConnectionLineCheckBox.Value)
                        plot(app.UIAxes,[app.x1+app.x2+app.x3+app.x4+app.x5 500],[app.y5+app.y4+app.y3+app.y2+app.y1 app.y5+app.y4+app.y3+app.y2+app.y1],'color','m','LineStyle','--','LineWidth',1);
                        plot(app.UIAxes2,[0 500+app.x1+app.x2+app.x3+app.x4+app.x5],[app.y5+app.y4+app.y3+app.y2+app.y1 app.y5+app.y4+app.y3+app.y2+app.y1],'color','m','LineStyle','--','LineWidth',1);
                     end
                    drawnow;
                end
            end
           
        end

        % Value changed function: Coefficient1RealEditField
        function Coefficient1RealEditFieldValueChanged(app, event)
            value = app.Coefficient1RealEditField.Value;
            app.x1 = value;
        end

        % Value changed function: Coefficient1ImaginerEditField
        function Coefficient1ImaginerEditFieldValueChanged(app, event)
            value = app.Coefficient1ImaginerEditField.Value;
            app.y1 = value;
        end

        % Value changed function: Coefficient2RealEditField
        function Coefficient2RealEditFieldValueChanged(app, event)
            value = app.Coefficient2RealEditField.Value;
            app.x2 = value;
        end

        % Value changed function: Coefficient2ImaginerEditField
        function Coefficient2ImaginerEditFieldValueChanged(app, event)
            value = app.Coefficient2ImaginerEditField.Value;
            app.y2 = value;
        end

        % Value changed function: Coefficient3RealEditField
        function Coefficient3RealEditFieldValueChanged(app, event)
            value = app.Coefficient3RealEditField.Value;
            app.x3 = value;
        end

        % Value changed function: Coefficient3ImaginerEditField
        function Coefficient3ImaginerEditFieldValueChanged(app, event)
            value = app.Coefficient3ImaginerEditField.Value;
            app.y3 = value;
        end

        % Value changed function: Coefficient4RealEditField
        function Coefficient4RealEditFieldValueChanged(app, event)
            value = app.Coefficient4RealEditField.Value;
            app.x4 = value;
        end

        % Value changed function: Coefficient4ImaginerEditField
        function Coefficient4ImaginerEditFieldValueChanged(app, event)
            value = app.Coefficient4ImaginerEditField.Value;
            app.y4 = value;
        end

        % Value changed function: Coefficient5RealEditField
        function Coefficient5RealEditFieldValueChanged(app, event)
            value = app.Coefficient5RealEditField.Value;
            app.x5 = value;
        end

        % Value changed function: Coefficient5ImaginerEditField
        function Coefficient5ImaginerEditFieldValueChanged(app, event)
            value = app.Coefficient5ImaginerEditField.Value;
            app.y5 = value;
        end

        % Value changed function: FundamentalFrequencyEditField
        function FundamentalFrequencyEditFieldValueChanged(app, event)
            value = app.FundamentalFrequencyEditField.Value;
            app.f = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Harmonic Circles')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes.YLim = [-2.5 2.5];
            app.UIAxes.XTick = [-2 -1 0 1 2];
            app.UIAxes.XTickLabel = {'-2'; '-1'; '0'; '1'; '2'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Summation of Harmonics')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 0.1];
            app.UIAxes2.YLim = [-2.5 2.5];
            app.UIAxes2.XTick = [0 0.04 0.08 0.12 0.16 0.2];
            app.UIAxes2.XTickLabel = {'0'; '0.04'; '0.08'; '0.12'; '0.16'; '0.2'};
            app.UIAxes2.YTick = [-2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5];
            app.UIAxes2.YTickLabel = {'-2.5'; '-2'; '-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'; '2'; '2.5'};
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.FundamentalFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FundamentalFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FundamentalFrequencyEditFieldValueChanged, true);
            app.FundamentalFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FundamentalFrequencyEditFieldLabel.Text = 'Fundamental Frequency';
            app.Coefficient1RealEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient1RealEditFieldLabel.Text = 'Coefficient 1 Real';
            app.Coefficient1RealEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient1RealEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient1RealEditFieldValueChanged, true);
            app.Coefficient1ImaginerEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient1ImaginerEditFieldLabel.Text = 'Coefficient 1 Imaginer';
            app.Coefficient1ImaginerEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient1ImaginerEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient1ImaginerEditFieldValueChanged, true);
            app.Coefficient2RealEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient2RealEditFieldLabel.Text = 'Coefficient 2 Real';
            app.Coefficient2RealEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient2RealEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient2RealEditFieldValueChanged, true);
            app.Coefficient2ImaginerEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient2ImaginerEditFieldLabel.Text = 'Coefficient 2 Imaginer';
            app.Coefficient2ImaginerEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient2ImaginerEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient2ImaginerEditFieldValueChanged, true);
            app.Coefficient3RealEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient3RealEditFieldLabel.Text = 'Coefficient 3 Real';
            app.Coefficient3RealEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient3RealEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient3RealEditFieldValueChanged, true);
            app.Coefficient3ImaginerEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient3ImaginerEditFieldLabel.Text = 'Coefficient 3 Imaginer';
            app.Coefficient3ImaginerEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient3ImaginerEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient3ImaginerEditFieldValueChanged, true);
            app.Coefficient4RealEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient4RealEditFieldLabel.Text = 'Coefficient 4 Real';
            app.Coefficient4RealEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient4RealEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient4RealEditFieldValueChanged, true);
            app.Coefficient4ImaginerEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient4ImaginerEditFieldLabel.Text = 'Coefficient 4 Imaginer';
            app.Coefficient4ImaginerEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient4ImaginerEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient4ImaginerEditFieldValueChanged, true);
            app.Coefficient5RealEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient5RealEditFieldLabel.Text = 'Coefficient 5 Real';
            app.Coefficient5RealEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient5RealEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient5RealEditFieldValueChanged, true);
            app.Coefficient5ImaginerEditFieldLabel = uilabel(app.UIFigure);
            app.Coefficient5ImaginerEditFieldLabel.Text = 'Coefficient 5 Imaginer';
            app.Coefficient5ImaginerEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coefficient5ImaginerEditField.ValueChangedFcn = createCallbackFcn(app, @Coefficient5ImaginerEditFieldValueChanged, true);
            app.CoefficientsLabel = uilabel(app.UIFigure);
            app.CoefficientsLabel.Text = 'Coefficients';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.ShowConnectionLineCheckBox = uicheckbox(app.UIFigure);
            app.ShowConnectionLineCheckBox.Text = 'Show Connection Line';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hw17011076

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end